classdef webpage_ranking_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WebpageRankingAnalysisUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputRankTab                    matlab.ui.container.Tab
        ReadyLabel                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RankWebsitesButton              matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        EnterWebsitescommaseperatedLabel  matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        AdvancedRankingsTab             matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RankWebsitesButton
        function RankWebsitesButtonPushed(app, event)
            app.ReadyLabel.Text = "Processing...";
app.ReadyLabel.FontColor = [0.3 0.3 0.8];
drawnow;

raw = app.EditField.Value;
if isempty(strtrim(raw))
    uialert(app.WebpageRankingAnalysisUIFigure, 'Please enter at least one domain.', 'Input Error'); 
    app.ReadyLabel.Text = "Ready";
    app.ReadyLabel.FontColor = [0 0 0];
    return;
end

% Parse & trim
sites = strtrim(strsplit(raw, ','));

% Validate domains
validDomainFormat = true;
invalidDomains = {};

for i = 1:numel(sites)
    domain = sites{i};
    % Check if domain appears to be valid
    if ~isempty(regexp(domain, '^https?://', 'once'))
        domain = regexprep(domain, '^https?://', '');
    end
    if isempty(regexp(domain, '^[a-zA-Z0-9][a-zA-Z0-9-]*\.[a-zA-Z]{2,}', 'once'))
        validDomainFormat = false;
        invalidDomains{end+1} = domain;
    end
end

if ~validDomainFormat
    invalidDomainsStr = strjoin(invalidDomains, ', ');
    uialert(app.WebpageRankingAnalysisUIFigure, ['The following domains appear to be invalid: ' invalidDomainsStr], 'Invalid Domains');
    app.ReadyLabel.Text = "Ready";
    app.ReadyLabel.FontColor = [0 0 0];
    return;
end

% 1) Validate reachability via robots.txt
app.ReadyLabel.Text = "Checking domain reachability...";
drawnow;

valid = {};
for i=1:numel(sites)
    d = sites{i};
    app.ReadyLabel.Text = sprintf("Checking domain %d of %d: %s", i, numel(sites), d);
    drawnow;
    try
        % Set a timeout to prevent hanging
        webread(['http://' d '/robots.txt'], weboptions('Timeout',5));
        valid{end+1} = d;
    catch
        % Try https if http fails
        try
            webread(['https://' d '/robots.txt'], weboptions('Timeout',5));
            valid{end+1} = d;
        catch
            % Skip unreachable domains
        end
    end
end

if isempty(valid)
    uialert(app.WebpageRankingAnalysisUIFigure, 'No valid/reachable domains found. Please check your internet connection or enter valid domains.', 'Error'); 
    app.ReadyLabel.Text = "Ready";
    app.ReadyLabel.FontColor = [0 0 0];
    return;
end

% 2) Query OpenPageRank API one-by-one
app.ReadyLabel.Text = "Fetching PageRank data...";
drawnow;

% IMPORTANT: Replace with your new API key
api_key = 'replace_with_ur_api_key';  % <-- UPDATE THIS LINE WITH YOUR NEW KEY
base_url = 'https://openpagerank.com/api/v1.0/getPageRank';
options = weboptions('HeaderFields',{'API-OPR',api_key}, 'Timeout',15);

% Create debug log file
logFile = fopen('api_debug_log.txt', 'w');
fprintf(logFile, 'API Debug Log\n');
fprintf(logFile, 'Date: %s\n\n', datestr(now));

m = numel(valid);
PRI = nan(m,1);  % PageRank Integer
PRD = nan(m,1);  % PageRank Decimal
DR = nan(m,1);   % Domain Rank

for i=1:m
    app.ReadyLabel.Text = sprintf("Fetching PageRank %d of %d: %s", i, m, valid{i});
    drawnow;
    try
        % Build query string
        url = [base_url '?domains[]=' valid{i}];
        
        % Log URL for debugging
        fprintf(logFile, 'Requesting URL: %s\n', url);
        
        % Make API request with longer timeout
        resp = webread(url, weboptions('HeaderFields',{'API-OPR',api_key}, 'Timeout',20));
        
        % Log raw response
        fprintf(logFile, 'API Response Type: %s\n', class(resp));
        if ischar(resp) || isstring(resp)
            fprintf(logFile, 'Raw Response: %s\n', resp);
        else
            fprintf(logFile, 'Response is not a string/char\n');
        end
        
        % Handle response format
        if ischar(resp) || isstring(resp)
            try
                resp = jsondecode(resp);
                fprintf(logFile, 'Successfully decoded JSON\n');
            catch jsonErr
                fprintf(logFile, 'JSON decode error: %s\n', jsonErr.message);
            end
        end
        
        % Check response structure
        if isstruct(resp) && isfield(resp, 'response')
            fprintf(logFile, 'Response has "response" field\n');
            data = resp.response;
            
            % Handle cell array or direct structure
            if iscell(data)
                if ~isempty(data)
                    fprintf(logFile, 'Response is a cell array with %d items\n', numel(data));
                    data = data{1};
                else
                    fprintf(logFile, 'Response is an empty cell array\n');
                    continue;
                end
            else
                fprintf(logFile, 'Response is not a cell array\n');
            end
            
            % Check for required fields
            if isstruct(data) && isfield(data, 'page_rank_integer') && isfield(data, 'page_rank_decimal') && isfield(data, 'rank')
                fprintf(logFile, 'Data has required fields\n');
                fprintf(logFile, 'Values: PRI=%d, PRD=%f, DR=%d\n', data.page_rank_integer, data.page_rank_decimal, data.rank);
                
                PRI(i) = data.page_rank_integer;
                PRD(i) = data.page_rank_decimal;
                DR(i) = data.rank;
            else
                fprintf(logFile, 'Missing required fields in data\n');
                if isstruct(data)
                    fields = fieldnames(data);
                    for f = 1:numel(fields)
                        fprintf(logFile, '  Available field: %s\n', fields{f});
                    end
                end
            end
        else
            fprintf(logFile, 'Response missing "response" field\n');
            if isstruct(resp)
                fields = fieldnames(resp);
                for f = 1:numel(fields)
                    fprintf(logFile, '  Available field: %s\n', fields{f});
                end
            end
        end
        
        fprintf(logFile, '\n---\n\n');
    catch e
        % Detailed error logging
        fprintf(logFile, 'API error for domain %s: %s\n', valid{i}, e.message);
        fprintf(logFile, 'Error details:\n%s\n\n', getReport(e));
    end
end

fclose(logFile);

% Check if any successful API calls
app.ReadyLabel.Text = "Processing API results...";
drawnow;

% Remove any NaN rows (failures)
ok = ~isnan(PRI);
valid = valid(ok); 
PRI = PRI(ok); 
PRD = PRD(ok); 
DR = DR(ok);

if isempty(valid)
    % Display more helpful error with debug log location
    uialert(app.WebpageRankingAnalysisUIFigure, ['All API calls failed. Please check your internet connection or API key. Debug log written to: ' pwd filesep 'api_debug_log.txt'], 'API Error'); 
    app.ReadyLabel.Text = "Ready";
    app.ReadyLabel.FontColor = [0 0 0];
    return;
end

% 3) Construct transition matrix and perform eigenvalue analysis
app.ReadyLabel.Text = "Calculating rankings...";
drawnow;

n = numel(valid);
adjacency_matrix = zeros(n);

% Build a more realistic link structure matrix
for i = 1:n
    for j = 1:n
        if i == j
            adjacency_matrix(i, j) = 0.3; 
        else
            % Avoid division by zero
            link_probability = PRD(j) * (1 / (max(DR(i), 1) + 1));
            adjacency_matrix(i, j) = link_probability;
        end
    end
end

% Normalize columns to sum to 1 (stochastic matrix)
transition_matrix = adjacency_matrix;
for j = 1:n
    col_sum = sum(transition_matrix(:, j));
    if col_sum > 0
        transition_matrix(:, j) = transition_matrix(:, j) / col_sum;
    else
        transition_matrix(:, j) = ones(n, 1) / n;
    end
end

% Apply damping factor (standard in PageRank)
damping = 0.85;
transition_matrix = damping * transition_matrix + (1 - damping) * ones(n) / n;

% Calculate PageRank using POWER ITERATION method
v = ones(n, 1) / n;
max_iter = 100;
tol = 1e-6;

% Track convergence for visualization
iterations = min(20, max_iter);
convergence_data = zeros(n, iterations);

% Power iteration
for iter = 1:max_iter
    v_new = transition_matrix * v;
    err = norm(v_new - v, 1);
    v = v_new;
    
    % Store convergence data for first iterations
    if iter <= iterations
        convergence_data(:, iter) = v;
    end
    
    if err < tol
        break;
    end
end

% Final eigenvector is our ranking
eig_rank = v;

% Normalize to sum to 1
eig_rank = eig_rank / sum(eig_rank);

% 4) Update results table
app.ReadyLabel.Text = "Updating results...";
drawnow;

% Create cell array for table
T = [valid(:), num2cell(PRI), num2cell(PRD), num2cell(DR), num2cell(eig_rank)];

% Sort by Eigen Rank (descending)
[~, sortIdx] = sort(eig_rank, 'descend');
T = T(sortIdx, :);
app.UITable.Data = T;

% Switch to results tab
app.TabGroup.SelectedTab = app.ResultsTab;

% 5) Create advanced rankings visualizations
app.ReadyLabel.Text = "Generating visualizations...";
drawnow;

% Get sorted data for visualization
sorted_PRI = PRI(sortIdx);
sorted_PRD = PRD(sortIdx);
sorted_DR = DR(sortIdx);
sorted_eig = eig_rank(sortIdx);
sorted_valid = valid(sortIdx);

% Delete any existing axes in the advanced tab
delete(findobj(app.AdvancedRankingsTab, 'Type', 'UIAxes'));

% Create new grid layout for visualizations
delete(findobj(app.AdvancedRankingsTab, 'Type', 'GridLayout'));

try
    % Create grid layout - wrapped in try/catch for older MATLAB versions
    grid_layout = uigridlayout(app.AdvancedRankingsTab, [2 2]);
    grid_layout.RowHeight = {'1x', '1x'};
    grid_layout.ColumnWidth = {'1x', '1x'};
    
    % Plot 1: Rankings Comparison Bar Chart
    ax1 = uiaxes(grid_layout);
    ax1.Layout.Row = 1;
    ax1.Layout.Column = 1;
catch
    % Fallback for older MATLAB versions without uigridlayout
    ax1 = uiaxes(app.AdvancedRankingsTab);
    ax1.Position = [20 300 400 250];
end

% Create bar chart
bar(ax1, [sorted_PRI sorted_PRD sorted_DR sorted_eig], 'grouped');
ax1.XTick = 1:numel(sorted_valid);
if numel(sorted_valid) <= 10  % Only show labels if not too many
    ax1.XTickLabel = sorted_valid;
    ax1.XTickLabelRotation = 45;
end
legend(ax1, {'PageRank Int', 'PageRank Dec', 'Domain Rank', 'Eigen Rank'}, 'Location', 'northeastoutside');
title(ax1, 'Comparison of Ranking Metrics');
ylabel(ax1, 'Value / Rank');
grid(ax1, 'on');

try
    % Plot 2: Transition Matrix Heatmap
    ax2 = uiaxes(grid_layout);
    ax2.Layout.Row = 1;
    ax2.Layout.Column = 2;
catch
    % Fallback for older MATLAB versions
    ax2 = uiaxes(app.AdvancedRankingsTab);
    ax2.Position = [440 300 400 250];
end

imagesc(ax2, transition_matrix);
title(ax2, 'Transition Matrix Heatmap');
xlabel(ax2, 'Destination');
ylabel(ax2, 'Source');
colorbar(ax2);
if numel(valid) <= 8  % Only show labels if not too many
    ax2.XTick = 1:n;
    ax2.YTick = 1:n;
    ax2.XTickLabel = valid;
    ax2.YTickLabel = valid;
    ax2.XTickLabelRotation = 45;
end

try
    % Plot 3: PageRank Distribution Pie Chart
    ax3 = uiaxes(grid_layout);
    ax3.Layout.Row = 2;
    ax3.Layout.Column = 1;
catch
    % Fallback for older MATLAB versions
    ax3 = uiaxes(app.AdvancedRankingsTab);
    ax3.Position = [20 20 400 250];
end

% Handle edge case of too many domains for a pie chart
if numel(sorted_valid) <= 8
    pie(ax3, sorted_PRD, sorted_valid);
else
    % Simplify for many domains
    [sorted_PRD_temp, idx] = sort(sorted_PRD, 'descend');
    top_domains = sorted_valid(idx(1:7));
    top_values = sorted_PRD_temp(1:7);
    other_value = sum(sorted_PRD_temp(8:end));
    pie_values = [top_values; other_value];
    pie_labels = [top_domains; {'Others'}];
    pie(ax3, pie_values, pie_labels);
end
title(ax3, 'PageRank Decimal Distribution');

try
    % Plot 4: Power Iteration Convergence
    ax4 = uiaxes(grid_layout);
    ax4.Layout.Row = 2;
    ax4.Layout.Column = 2;
catch
    % Fallback for older MATLAB versions
    ax4 = uiaxes(app.AdvancedRankingsTab);
    ax4.Position = [440 20 400 250];
end

% Show convergence curves
for i = 1:min(n, 10)  % Limit to 10 lines for readability
    plot(ax4, 1:iterations, convergence_data(i, :), 'LineWidth', 1.5);
    hold(ax4, 'on');
end

title(ax4, 'Power Iteration Convergence');
xlabel(ax4, 'Iteration');
ylabel(ax4, 'Component Value');
if n <= 10
    legend(ax4, valid, 'Location', 'eastoutside');
else
    legend(ax4, valid(1:10), 'Location', 'eastoutside');
end
grid(ax4, 'on');

% Switch to advanced tab
app.TabGroup.SelectedTab = app.AdvancedRankingsTab;

% Done
app.ReadyLabel.Text = "Analysis complete";
app.ReadyLabel.FontColor = [0 0.5 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WebpageRankingAnalysisUIFigure = uifigure('Visible', 'off');
            app.WebpageRankingAnalysisUIFigure.Name = 'Webpage Ranking Analysis';
            app.TabGroup = uitabgroup(app.WebpageRankingAnalysisUIFigure);
            app.InputRankTab = uitab(app.TabGroup);
            app.InputRankTab.Title = 'Input&Rank';
            app.EnterWebsitescommaseperatedLabel = uilabel(app.InputRankTab);
            app.EnterWebsitescommaseperatedLabel.Text = 'Enter Websites (comma seperated)';
            app.EditFieldLabel = uilabel(app.InputRankTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.InputRankTab, 'text');
            app.EditField.Placeholder = 'example.com,google.com';
            app.RankWebsitesButton = uibutton(app.InputRankTab, 'push');
            app.RankWebsitesButton.ButtonPushedFcn = createCallbackFcn(app, @RankWebsitesButtonPushed, true);
            app.RankWebsitesButton.Text = 'Rank Websites';
            app.Label = uilabel(app.InputRankTab);
            app.Label.Text = 'This tool fetches PageRank data from OpenPageRank API and performs advanced ranking analysis using eigenvalue decomposition. Enter domain names separated by commas (e.g., example.com, google.com) and click the button to analyze them.';
            app.ReadyLabel = uilabel(app.InputRankTab);
            app.ReadyLabel.Text = {'Ready'; ''};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'''Website'''; '''PageRank Int'''; '''PageRank Dec'''; '''Domain Rank'''; '''Eigen Rank'''};
            app.UITable.RowName = {};
            app.AdvancedRankingsTab = uitab(app.TabGroup);
            app.AdvancedRankingsTab.Title = 'Advanced Rankings';
            % Show the figure after all components are created
            app.WebpageRankingAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webpage_ranking_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WebpageRankingAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WebpageRankingAnalysisUIFigure)
        end
    end
end